classdef B10607044 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        Image                          matlab.ui.control.Image
        Label                          matlab.ui.control.Label
        Button                         matlab.ui.control.StateButton
        Slider                         matlab.ui.control.Slider
        DeleteButton                   matlab.ui.control.StateButton
        RightPanel                     matlab.ui.container.Panel
        Image_2                        matlab.ui.control.Image
        Label_2                        matlab.ui.control.Label
        OutputFrameRateEditFieldLabel  matlab.ui.control.Label
        OutputFrameRateEditField       matlab.ui.control.NumericEditField
        HeightEditFieldLabel           matlab.ui.control.Label
        HeightEditField                matlab.ui.control.NumericEditField
        WidthEditFieldLabel            matlab.ui.control.Label
        WidthEditField                 matlab.ui.control.NumericEditField
        FileMenu                       matlab.ui.container.Menu
        OpenMenu                       matlab.ui.container.Menu
        SaveAsMenu                     matlab.ui.container.Menu
        ExitMenu                       matlab.ui.container.Menu
        ProcessMenu                    matlab.ui.container.Menu
        NegativeMenu                   matlab.ui.container.Menu
        LRFlipMenu                     matlab.ui.container.Menu
        DarkerMenu                     matlab.ui.container.Menu
        GaussianFilterMenu             matlab.ui.container.Menu
        EdgeCannyMenu                  matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        vidObj%原始影像
        vidObj_out%暫存輸出影像
        watermark = imread('watermark.png');%浮水印
        im%左邊顯示畫面
        p = 1%影像處理選項
        delete_list={};%Delete的紀錄
        
    end
    
    methods (Access = private)
        
        function results = func(app)%影像處理功能
            switch app.p
                case 1%負片
                    results = 255- app.im;
                    app.Label_2.Text = 'Negative';
                case 2%左右反轉
                    results = fliplr(app.im);
                    app.Label_2.Text = 'LR Flip';
                case 3%變黑
                    results = app.im - 64;
                    app.Label_2.Text = 'Darker';
                case 4%模糊
                    results = imgaussfilt(app.im,2);
                    app.Label_2.Text = 'Gaussian Filter';
                case 5%邊緣影像
                    results = edge(rgb2gray(app.im),'canny');
                    results = uint8(results(:,:,[1 1 1]) * 255 );
                    app.Label_2.Text = 'Edge(Canny)';
            end
            if app.DeleteButton.Value%按下Delete畫面更黑
                results = results - 100;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            app.Image.ScaleMethod = 'stretch';
            app.Image_2.ScaleMethod = 'stretch';
            app.Slider.Enable = 0;%鎖Slider
            app.Button.Enable =0;%鎖Button
            app.DeleteButton.Enable = 0;%鎖DeleteButton
            app.SaveAsMenu.Enable = 0;%鎖SaveAsMenu
            app.ProcessMenu.Enable = 0;%鎖ProcessMenu
            app.OutputFrameRateEditField.Enable = 0;%鎖OutputFrameRateEditField
            app.HeightEditField.Enable = 0;%鎖HeightEditField
            app.WidthEditField.Enable = 0;%鎖WidthEditField
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {484, 484};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {547, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            app.Slider.Enable = 1;%打開Slider
            app.DeleteButton.Enable = 1;%打開DeleteButton
            app.Button.Enable = 1;%打開Button
            app.SaveAsMenu.Enable = 1;%打開SaveAsMenu
            app.ProcessMenu.Enable = 1;%打開ProcessMenu
            app.OutputFrameRateEditField.Enable = 1;%打開OutputFrameRateEditField
            app.HeightEditField.Enable = 1;%打開HeightEditField
            app.WidthEditField.Enable = 1;%打開WidthEditField
            [filename, pathname]  = uigetfile({'*.mp4';'*.avi';'*.mpg'},'read a video file'); 
            app.vidObj = VideoReader([pathname filename]);
            app.Slider.Limits(2)=app.vidObj.Duration;%將Slider上限改成影片長度
            app.Slider.Value = app.vidObj.CurrentTime;%將Slider Value改成影片現在時間
            app.OutputFrameRateEditField.Value = app.vidObj.FrameRate;%預設輸出FrameRate與輸入影片相同
            app.HeightEditField.Value = app.vidObj.Height;%預設輸出Height與輸入影片相同
            app.WidthEditField.Value = app.vidObj.Width;%預設輸出Width與輸入影片相同
            app.Label.Text = num2str(app.vidObj.CurrentTime,'%.2f');%顯示影片現在時間
            app.im = read(app.vidObj, 1);%將影片現在時間讀入im
            app.Image.ImageSource = app.im;%show im在左圖
            app.Image_2.ImageSource = func(app);%將im進行影像處理後放右圖
            app.vidObj_out = VideoWriter('temp','MPEG-4');%創一個暫存檔
            app.vidObj_out.Quality = 90; % default quality = 75
            [h w ch]=size(func(app));%存影片長寬
            app.watermark=imresize(app.watermark,[h w]);%更改浮水印長寬與影片相符
            open(app.vidObj_out);%將vidObj_out打開
            writeVideo(app.vidObj_out, func(app));%將輸出影像存入暫存檔
            figure(app.UIFigure)
        end

        % Callback function: ExitMenu, UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Menu selected function: NegativeMenu
        function NegativeMenuSelected(app, event)
            app.p = 1;
            app.Image_2.ImageSource = func(app);
        end

        % Menu selected function: LRFlipMenu
        function LRFlipMenuSelected(app, event)
            app.p = 2;
            app.Image_2.ImageSource = func(app);
        end

        % Menu selected function: DarkerMenu
        function DarkerMenuSelected(app, event)
            app.p = 3;
            app.Image_2.ImageSource = func(app);
        end

        % Value changed function: Button
        function ButtonValueChanged(app, event)
            value = app.Button.Value;
            if value%判斷是否為播放
                app.Button.Icon = 'pause.jpg';%顯示暫停符號
                app.FileMenu.Enable = 0;%鎖FileMenu
                app.ProcessMenu.Enable = 0;%鎖ProcessMenu
                app.Slider.Enable = 0;%鎖Slider
                app.OutputFrameRateEditField.Enable = 0;%鎖OutputFrameRateEditField
                app.HeightEditField.Enable = 0;%鎖HeightEditField
                app.WidthEditField.Enable = 0;%鎖WidthEditField
                while hasFrame(app.vidObj)%判斷是否還有Frame
                    if app.Button.Value == 0%中途退出
                        break;
                    end
                    app.im=readFrame(app.vidObj);%影像讀入im
                    pause(1/app.vidObj.FrameRate)%暫停
                    if app.vidObj.CurrentTime<app.Slider.Limits(2)%防止影片現在時間超過上限
                        app.Slider.Value = app.vidObj.CurrentTime;
                    else
                        app.Slider.Value = app.Slider.Limits(2);
                    end
                    app.Label.Text = num2str(app.vidObj.CurrentTime,'%.2f');%顯示影片現在時間
                    app.Image.ImageSource = app.im;%show im在左圖
                    app.Image_2.ImageSource = func(app);%show 影像處理後的圖在右邊
                    if ~app.DeleteButton.Value%如果Delete沒按下
                        temp = func(app) + 0.2*app.watermark;%放浮水印
                        writeVideo(app.vidObj_out, temp);%將此Frame放入暫存檔
                    end
                end
                if ~(hasFrame(app.vidObj))%如果沒有Frame
                    app.vidObj.CurrentTime = 0;%將影片拉回最前面
                    app.Label.Text = num2str(app.vidObj.CurrentTime,'%.2f');%更改影片現在時間
                    app.Slider.Value = app.vidObj.CurrentTime;%更改Slider
                    app.Button.Value = 0;%將Button設置成暫停
                    app.FileMenu.Enable = 1;%打開FileMenu
                    app.ProcessMenu.Enable = 1;%打開ProcessMenu
                    app.Slider.Enable = 1;%打開Slider
                    app.Button.Icon = 'down.jpg';%顯示播放圖案
                end
            else%如果為暫停
                app.FileMenu.Enable = 1;%打開FileMenu
                app.ProcessMenu.Enable = 1;%打開ProcessMenu
                app.Slider.Enable = 1;%打開Slider
                app.OutputFrameRateEditField.Enable = 1;%打開OutputFrameRateEditField
                app.HeightEditField.Enable = 1;%打開HeightEditField
                app.WidthEditField.Enable = 1;%打開WidthEditField
                app.Button.Icon = 'down.jpg';
            end
        end

        % Menu selected function: SaveAsMenu
        function SaveAsMenuSelected(app, event)
            close(app.vidObj_out); %將暫存檔暫停讀取
            [filename pathname] = uiputfile({'*.mp4'},'Save a video file');
            vidObj2 = VideoWriter([pathname filename],'MPEG-4');%寫入使用者自選的檔名路徑
            vidObj2.Quality = 90; % default quality = 75
            vidObj2.FrameRate = app.OutputFrameRateEditField.Value;
            open(vidObj2);%打開vidObj2
            reader = VideoReader('temp.mp4');%將暫存檔讀入
            
            while hasFrame(reader)%將暫存檔寫入使用者的檔案                
                writeVideo(vidObj2, imresize(readFrame(reader),[app.HeightEditField.Value app.WidthEditField.Value]));
            end
            close(vidObj2);%關閉vidObj2 完成寫入  
            delete('temp.mp4')%刪除暫存檔
            if  ~isempty(app.delete_list)%判斷是否有按Delete
                fprintf('start\tend\n')
                for i=1:2:length(app.delete_list)%印出資料
                    fprintf('%.2f\t%.2f\n',app.delete_list{i},app.delete_list{i+1})
                end
                app.delete_list={};%清空
            end
            app.vidObj_out = VideoWriter('temp','MPEG-4');%清空暫存檔
            app.vidObj_out.Quality = 90; % default quality = 75
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            app.vidObj.CurrentTime = app.Slider.Value; %將更改值寫入影片時間
            app.Label.Text = num2str(app.vidObj.CurrentTime,'%.2f'); %更改顯示時間
            app.im=readFrame(app.vidObj); %讀入影片Frame
            app.Image.ImageSource = app.im; %顯示影片Frame
            app.Image_2.ImageSource = func(app); %顯示影像處理後影片Frame
        end

        % Value changed function: DeleteButton
        function DeleteButtonValueChanged(app, event)
            if app.DeleteButton.Value
                app.delete_list{end+1} = app.vidObj.CurrentTime;%按下Delete紀錄開始時間
            else
                app.delete_list{end+1} = app.vidObj.CurrentTime;%放開Delete紀錄結束時間
            end
            
        end

        % Menu selected function: GaussianFilterMenu
        function GaussianFilterMenuSelected(app, event)
            app.p = 4;
            app.Image_2.ImageSource = func(app);
        end

        % Menu selected function: EdgeCannyMenu
        function EdgeCannyMenuSelected(app, event)
            app.p = 5;
            app.Image_2.ImageSource = func(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Image = uiimage(app.LeftPanel);
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = '';
            app.Button = uibutton(app.LeftPanel, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Button.Icon = 'down.jpg';
            app.Button.Text = '';
            app.Slider = uislider(app.LeftPanel);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.DeleteButton = uibutton(app.LeftPanel, 'state');
            app.DeleteButton.ValueChangedFcn = createCallbackFcn(app, @DeleteButtonValueChanged, true);
            app.DeleteButton.Text = 'Delete';
            app.RightPanel = uipanel(app.GridLayout);
            app.Image_2 = uiimage(app.RightPanel);
            app.Label_2 = uilabel(app.RightPanel);
            app.Label_2.Text = '';
            app.OutputFrameRateEditFieldLabel = uilabel(app.RightPanel);
            app.OutputFrameRateEditFieldLabel.Text = 'Output Frame Rate';
            app.OutputFrameRateEditField = uieditfield(app.RightPanel, 'numeric');
            app.OutputFrameRateEditField.Limits = [1 60];
            app.OutputFrameRateEditField.Value = 30;
            app.HeightEditFieldLabel = uilabel(app.RightPanel);
            app.HeightEditFieldLabel.Text = 'Height';
            app.HeightEditField = uieditfield(app.RightPanel, 'numeric');
            app.HeightEditField.Limits = [100 1000];
            app.HeightEditField.Value = 320;
            app.WidthEditFieldLabel = uilabel(app.RightPanel);
            app.WidthEditFieldLabel.Text = 'Width';
            app.WidthEditField = uieditfield(app.RightPanel, 'numeric');
            app.WidthEditField.Limits = [100 1000];
            app.WidthEditField.Value = 640;
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.SaveAsMenu = uimenu(app.FileMenu);
            app.SaveAsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveAsMenuSelected, true);
            app.SaveAsMenu.Text = 'Save As';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ExitMenu.Separator = 'on';
            app.ExitMenu.Text = 'Exit';
            app.ProcessMenu = uimenu(app.UIFigure);
            app.ProcessMenu.Text = 'Process';
            app.NegativeMenu = uimenu(app.ProcessMenu);
            app.NegativeMenu.MenuSelectedFcn = createCallbackFcn(app, @NegativeMenuSelected, true);
            app.NegativeMenu.Text = 'Negative';
            app.LRFlipMenu = uimenu(app.ProcessMenu);
            app.LRFlipMenu.MenuSelectedFcn = createCallbackFcn(app, @LRFlipMenuSelected, true);
            app.LRFlipMenu.Text = 'LR Flip';
            app.DarkerMenu = uimenu(app.ProcessMenu);
            app.DarkerMenu.MenuSelectedFcn = createCallbackFcn(app, @DarkerMenuSelected, true);
            app.DarkerMenu.Text = 'Darker';
            app.GaussianFilterMenu = uimenu(app.ProcessMenu);
            app.GaussianFilterMenu.MenuSelectedFcn = createCallbackFcn(app, @GaussianFilterMenuSelected, true);
            app.GaussianFilterMenu.Text = 'Gaussian Filter';
            app.EdgeCannyMenu = uimenu(app.ProcessMenu);
            app.EdgeCannyMenu.MenuSelectedFcn = createCallbackFcn(app, @EdgeCannyMenuSelected, true);
            app.EdgeCannyMenu.Text = 'Edge(Canny)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = B10607044

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end